classdef IF4073_Plate_Detection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        DetectedPlateTextPanel    matlab.ui.container.Panel
        Label                     matlab.ui.control.Label
        OutputImagesPanel         matlab.ui.container.Panel
        ImageSrc                  matlab.ui.control.Image
        ImageGray                 matlab.ui.control.Image
        ImageCrop                 matlab.ui.control.Image
        ImageRotation             matlab.ui.control.Image
        GrayLabel                 matlab.ui.control.Label
        CropLabel                 matlab.ui.control.Label
        RotationLabel             matlab.ui.control.Label
        SourceLabel               matlab.ui.control.Label
        BrowseforImagesButton     matlab.ui.control.Button
        ConfigurationsPanel       matlab.ui.container.Panel
        PlateTemplateButtonGroup  matlab.ui.container.ButtonGroup
        Template3Button           matlab.ui.control.RadioButton
        Template2Button           matlab.ui.control.RadioButton
        Template1Button           matlab.ui.control.RadioButton
        RotateImageCheckBox       matlab.ui.control.CheckBox
        CropImageCheckBox         matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        OriginalImg
        GrayImg
        RotatedImg
        CroppedImg
        TemplateImg
        Template = '0123456789ABCDEFGHIJKLMN0PQRSTUVWXYZ';
        Running = false;
    end
    methods (Access = private)
        function Execute(app, I)
            if(app.Running)
                % Grayscale
                app.GrayImg = rgb2gray(I);
                app.ImageGray.ImageSource = cat(3, app.GrayImg, app.GrayImg, app.GrayImg);

                %Rotate Mode
                if app.RotateImageCheckBox.Value
                    app.RotatedImg = app.Rotation(app.GrayImg);
                    app.ImageRotation.ImageSource = cat(3, app.RotatedImg, app.RotatedImg, app.RotatedImg);
                else
                    app.RotatedImg = app.GrayImg;
                    app.ImageRotation.ImageSource = '';
                end
    
                % Crop Mode
                if app.CropImageCheckBox.Value
                    app.CroppedImg = app.Crop(app.RotatedImg);
                    app.ImageCrop.ImageSource = cat(3, app.CroppedImg, app.CroppedImg, app.CroppedImg);
                else
                    app.CroppedImg = app.RotatedImg;
                    app.ImageCrop.ImageSource = '';
                end

                app.Label.Text = app.Detect(app.CroppedImg);
            end
        end
        
        %Crop 
        function result = Crop(~, I)
            cropImage = edge(I, 'prewitt');
            cropRegionProp = regionprops(cropImage, 'BoundingBox', 'Area', 'MajorAxisLength', 'MinorAxisLength');
            cropArea = cropRegionProp(1).Area;
            cropBoundingBox = cropRegionProp(1).BoundingBox;
            cropCount = numel(cropRegionProp);
            cropMaxArea = cropArea;
            for i=2:cropCount
                cropRatio = cropRegionProp(i).MajorAxisLength / cropRegionProp(i).MinorAxisLength;
                if cropRatio > 2 && cropRatio < 7 && cropMaxArea < cropRegionProp(i).Area
                    cropMaxArea = cropRegionProp(i).Area;
                    cropBoundingBox = cropRegionProp(i).BoundingBox;
                end
            end
            
            result = imcrop(I, cropBoundingBox);
        end

        %Rotate
        function result = Rotation(~, I)
            %Edge Detection
            binaryImg = edge(I,'canny');
            binaryImg = bwmorph(binaryImg,'thicken');

            %Radon Transform
            theta = -90:89;
            [R,~] = radon(binaryImg,theta);
            [R1,~] = max(R); 
            theta_max = 90;
            while(theta_max > 50 || theta_max<-50)
                [~,theta_max] = max(R1);
                R1(theta_max) = 0;
                theta_max = theta_max - 91;
            end
            result = imrotate(I,-theta_max);
            result(result == 0) = 255;
        end
        
        function matchCharRes = matchChar(~, K, T, template)
            matchCharRes = "";
            for z = 1: size(K, 3)
                i = templateMatching(K(:,:,z), T);
                matchCharRes = matchCharRes + template(i);
            end
        end
        %Plate Detection
        function result = Detect(app, I)
            try
                plateImg = getChar(I, 9);
                plateNumber = app.matchChar(plateImg, app.TemplateImg, app.Template);
                result = plateNumber;
            catch
                result = "Matching failed";
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            G = rgb2gray(imread("charTemplate1.png"));
            app.TemplateImg = getChar(G, 36);
        end

        % Button pushed function: BrowseforImagesButton
        function BrowseforImagesButtonPushed(app, event)
            [file,path] = uigetfile({'*.png;*.jpg;*.jpeg','Images'});
            if (file ~= 0)
                app.OriginalImg = imread(fullfile(path,file));
                app.ImageSrc.ImageSource = app.OriginalImg;
                app.Running = true;
                app.Execute(app.OriginalImg);
            end
        end

        % Selection changed function: PlateTemplateButtonGroup
        function PlateTemplateDropDownValueChanged(app, event)
            if (app.Template1Button.Value)
                G = rgb2gray(imread("charTemplate1.png"));
            end
            if (app.Template2Button.Value)
                G = rgb2gray(imread("charTemplate2.png"));
            end
            if (app.Template3Button.Value)
                G = rgb2gray(imread("charTemplate3.png"));
            end
            app.TemplateImg = getChar(G, 36);
            app.Execute(app.ImageSrc.ImageSource);
        end

        % Value changed function: CropImageCheckBox, RotateImageCheckBox
        function RotateImageCheckBoxValueChanged(app, event)
            app.Execute(app.ImageSrc.ImageSource);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ConfigurationsPanel = uipanel(app.UIFigure);
            app.ConfigurationsPanel.Title = 'Configurations';
            app.CropImageCheckBox = uicheckbox(app.ConfigurationsPanel);
            app.CropImageCheckBox.ValueChangedFcn = createCallbackFcn(app, @RotateImageCheckBoxValueChanged, true);
            app.CropImageCheckBox.Text = 'Crop Image';
            app.RotateImageCheckBox = uicheckbox(app.ConfigurationsPanel);
            app.RotateImageCheckBox.ValueChangedFcn = createCallbackFcn(app, @RotateImageCheckBoxValueChanged, true);
            app.RotateImageCheckBox.Text = 'Rotate Image';
            app.PlateTemplateButtonGroup = uibuttongroup(app.ConfigurationsPanel);
            app.PlateTemplateButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlateTemplateDropDownValueChanged, true);
            app.PlateTemplateButtonGroup.Title = 'Plate Template';
            app.Template1Button = uiradiobutton(app.PlateTemplateButtonGroup);
            app.Template1Button.Text = 'Template 1';
            app.Template1Button.Value = true;
            app.Template2Button = uiradiobutton(app.PlateTemplateButtonGroup);
            app.Template2Button.Text = 'Template 2';
            app.Template3Button = uiradiobutton(app.PlateTemplateButtonGroup);
            app.Template3Button.Text = 'Template 3';
            app.BrowseforImagesButton = uibutton(app.UIFigure, 'push');
            app.BrowseforImagesButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseforImagesButtonPushed, true);
            app.BrowseforImagesButton.Text = 'Browse for Image(s) ...';
            app.OutputImagesPanel = uipanel(app.UIFigure);
            app.OutputImagesPanel.Title = 'Output Images';
            app.SourceLabel = uilabel(app.OutputImagesPanel);
            app.SourceLabel.Text = 'Source';
            app.RotationLabel = uilabel(app.OutputImagesPanel);
            app.RotationLabel.Text = 'Rotation';
            app.CropLabel = uilabel(app.OutputImagesPanel);
            app.CropLabel.Text = 'Crop';
            app.GrayLabel = uilabel(app.OutputImagesPanel);
            app.GrayLabel.Text = 'Gray';
            app.ImageRotation = uiimage(app.OutputImagesPanel);
            app.ImageCrop = uiimage(app.OutputImagesPanel);
            app.ImageGray = uiimage(app.OutputImagesPanel);
            app.ImageSrc = uiimage(app.OutputImagesPanel);
            app.DetectedPlateTextPanel = uipanel(app.UIFigure);
            app.DetectedPlateTextPanel.Title = 'Detected Plate Text';
            app.Label = uilabel(app.DetectedPlateTextPanel);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IF4073_Plate_Detection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end